classdef weather_impact_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FirstyearEditFieldLabel         matlab.ui.control.Label
        firstyear                       matlab.ui.control.NumericEditField
        LastyearEditFieldLabel          matlab.ui.control.Label
        lastyear                        matlab.ui.control.NumericEditField
        RunObservationButton            matlab.ui.control.Button
        LowerquantileLabel              matlab.ui.control.Label
        Lowerquantile                   matlab.ui.control.NumericEditField
        HigherquantileLabel             matlab.ui.control.Label
        Higherquantile                  matlab.ui.control.NumericEditField
        dependent                       matlab.ui.control.Button
        DependentvariableLabel          matlab.ui.control.Label
        ObservationtypeButtonGroup      matlab.ui.container.ButtonGroup
        SolarwindcombinedButton         matlab.ui.control.RadioButton
        SolaronlyButton                 matlab.ui.control.RadioButton
        WindonlyButton                  matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ExplanatoryVar1                 matlab.ui.control.Button
        ExplanatoryVar2                 matlab.ui.control.Button
        ExplanatoryvariablesolarLabel_2  matlab.ui.control.Label
        ExplanatoryvariablewindLabel_2  matlab.ui.control.Label
        ClosecurrentfiguresButton       matlab.ui.control.Button
        AlgorithmtypeButtonGroup        matlab.ui.container.ButtonGroup
        QuantilesButton                 matlab.ui.control.RadioButton
        LimitsButton                    matlab.ui.control.RadioButton
        LowerlimitLabel                 matlab.ui.control.Label
        LowerlimitEditField             matlab.ui.control.NumericEditField
        HigherlimitLabel                matlab.ui.control.Label
        HigherlimitEditField            matlab.ui.control.NumericEditField
        ClearworkspaceandcommandwindowButton  matlab.ui.control.Button
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        path
        type % Description
        quantile % Description
        algo % Description
        limit % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunObservationButton
        function RunObservationButtonPushed(app, event)
            
            % if no dependent variable was chosen, open window to select
            % file
            if strcmp(app.dependent.Text, 'Browse variable...')
                [app.dependent.Text, app.path] = uigetfile('*.mat');
                app.dependent.FontWeight = 'Normal';
            end
            
            % run the script to identify weather extremes for chosen
            % quantile or limits. Start with smaller percentile (low
            % extremes), or smaller limit.
            %app.algo = 'quantile';
            app.type = 'dd';
            app.quantile = app.Lowerquantile.Value;
            app.limit = app.LowerlimitEditField.Value;
            [days_sunDD, days_windDD, days_DD] = weather_event_identifier(app);
            
            % adjust parameters and continue with higher percentile (high
            % extremes)
            app.type = 'bb';
            app.quantile = 100 - app.Higherquantile.Value;
            app.limit = app.HigherlimitEditField.Value;
            [days_sunBB, days_windBB, days_BB] = weather_event_identifier(app);
            
            % clear axes on the app interface
            cla(app.UIAxes);
            cla(app.UIAxes2);
            
            % on app interface are three options for the observation type:
            % solar, wind, or both combined
            % dependent on the chosen option: evaluate specific option
            
            if app.SolarwindcombinedButton.Value
                % plot days of solar and wind combined observation
                if size(days_DD,1) == 0 
                    msgbox(['No days for given parameters for lower limit identified. ' ...
                        'Increase lower limit and retry.'])
                    return
                end
                if size(days_BB,1) == 0 
                    msgbox(['No days for given parameters for upper limit identified. ' ...
                        'Decrease upper limit and retry.'])
                    return
                end
                plot_days(app, days_DD, days_BB);
                % observe impact of combined observation
                [impact_DD, impact_BB] = weather_impact(app, days_DD, days_BB);
                % plot impact observation
                plot_impacts(impact_DD, impact_BB);
            
            elseif app.SolaronlyButton.Value
                
                % problem in hourly dataset: when observing only solar
                % data, there are eventually too many values equal to zero
                % to obtain values greater than zero in the impact
                % observation. Therefore a warning is implemented giving
                % the opportunity to exit and adjust the parameters.
                if sum(days_sunDD(:,end-2)) == 0
                    answer = questdlg(['Hourly solar radiation data contains more values == 0 ' ...
                        'as the choosen quantile size is large. To display extreme values of the ' ...
                        'whole year, choose a larger quantile, or a smaller period. '], ...
                        'Warning','Continue', 'Adjust parameters', 'Continue');
                    if strcmp(answer, 'Adjust parameters')
                        return
                    end
                    clear answer
                end
                if size(days_DD,1) == 0 
                    msgbox(['No days for given parameters for lower limit identified. ' ...
                        'Increase lower limit and retry.'])
                    return
                end
                if size(days_BB,1) == 0 
                    msgbox(['No days for given parameters for upper limit identified. ' ...
                        'Decrease upper limit and retry.'])
                    return
                end
                % plot days of solar observation
                plot_days(app, days_sunDD, days_sunBB);
                % observe impact of solar observation
                [impact_DD, impact_BB] = weather_impact(app, days_sunDD, days_sunBB);
                % plot impact observation
                plot_impacts(impact_DD, impact_BB);  
            
            elseif app.WindonlyButton.Value
                if size(days_DD,1) == 0 
                    msgbox(['No days for given parameters for lower limit identified. ' ...
                        'Increase lower limit and retry.'])
                    return
                end
                if size(days_BB,1) == 0 
                    msgbox(['No days for given parameters for upper limit identified. ' ...
                        'Decrease upper limit and retry.'])
                    return
                end
                % plot days of wind observation
                plot_days(app, days_windDD, days_windBB); 
                % observe impact of wind observation
                [impact_DD, impact_BB] = weather_impact(app, days_windDD, days_windBB);
                % plot impact observation
                plot_impacts(impact_DD, impact_BB);   
                
            end
            
        end

        % Button pushed function: dependent
        function dependentButtonPushed(app, event)
            [app.dependent.Text, app.path] = uigetfile({'*.mat'}, 'Select dependent variable');
            app.dependent.FontWeight = 'Normal';
        end

        % Button pushed function: ExplanatoryVar1
        function ExplanatoryVar1ButtonPushed(app, event)
            [app.ExplanatoryVar1.Text, ~] = uigetfile({'*.mat'}, 'Select explanatory solar variable');  
        end

        % Button pushed function: ExplanatoryVar2
        function ExplanatoryVar2ButtonPushed(app, event)
            [app.ExplanatoryVar2.Text, ~] = uigetfile({'*.mat'}, 'Select explanatory wind variable');  
        end

        % Button pushed function: ClosecurrentfiguresButton
        function ClosecurrentfiguresButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            close all
        end

        % Selection changed function: AlgorithmtypeButtonGroup
        function AlgorithmtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgorithmtypeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,app.QuantilesButton.Text)
                app.Lowerquantile.Enable = 'on';
                app.Higherquantile.Enable = 'on';
                app.LowerlimitEditField.Enable = 'off';
                app.HigherlimitEditField.Enable = 'off';
            elseif strcmp(selectedButton.Text,app.LimitsButton.Text)
                app.Lowerquantile.Enable = 'off';
                app.Higherquantile.Enable = 'off';
                app.LowerlimitEditField.Enable = 'on';
                app.HigherlimitEditField.Enable = 'on';  
            end
        end

        % Button pushed function: 
        % ClearworkspaceandcommandwindowButton
        function ClearworkspaceandcommandwindowButtonPushed(app, event)
            clear variables
            clc
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FirstyearEditFieldLabel = uilabel(app.UIFigure);
            app.FirstyearEditFieldLabel.Text = 'First year';
            app.firstyear = uieditfield(app.UIFigure, 'numeric');
            app.firstyear.Value = 2013;
            app.LastyearEditFieldLabel = uilabel(app.UIFigure);
            app.LastyearEditFieldLabel.Text = 'Last year';
            app.lastyear = uieditfield(app.UIFigure, 'numeric');
            app.lastyear.Value = 2016;
            app.RunObservationButton = uibutton(app.UIFigure, 'push');
            app.RunObservationButton.ButtonPushedFcn = createCallbackFcn(app, @RunObservationButtonPushed, true);
            app.RunObservationButton.Text = 'Run observation';
            app.LowerquantileLabel = uilabel(app.UIFigure);
            app.LowerquantileLabel.Text = 'Lower quantile [%]';
            app.Lowerquantile = uieditfield(app.UIFigure, 'numeric');
            app.Lowerquantile.Value = 10;
            app.HigherquantileLabel = uilabel(app.UIFigure);
            app.HigherquantileLabel.Text = 'Higher quantile [%]';
            app.Higherquantile = uieditfield(app.UIFigure, 'numeric');
            app.Higherquantile.Value = 90;
            app.dependent = uibutton(app.UIFigure, 'push');
            app.dependent.ButtonPushedFcn = createCallbackFcn(app, @dependentButtonPushed, true);
            app.dependent.Text = 'Browse variable...';
            app.DependentvariableLabel = uilabel(app.UIFigure);
            app.DependentvariableLabel.Text = 'Dependent variable';
            app.ObservationtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ObservationtypeButtonGroup.Title = 'Observation type';
            app.SolarwindcombinedButton = uiradiobutton(app.ObservationtypeButtonGroup);
            app.SolarwindcombinedButton.Text = 'Solar & wind combined';
            app.SolarwindcombinedButton.Value = true;
            app.SolaronlyButton = uiradiobutton(app.ObservationtypeButtonGroup);
            app.SolaronlyButton.Text = 'Solar only';
            app.WindonlyButton = uiradiobutton(app.ObservationtypeButtonGroup);
            app.WindonlyButton.Text = 'Wind only';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Intensity of wind speed on detected events')
            xlabel(app.UIAxes, 'Day of year')
            ylabel(app.UIAxes, 'Wind intensity [%]')
            app.UIAxes.XLim = [1 366];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.XTick = [1 100 200 300];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Intensity of solar radiation on detected events')
            xlabel(app.UIAxes2, 'Day of year')
            ylabel(app.UIAxes2, 'Solar intensity [%]')
            app.UIAxes2.XLim = [1 366];
            app.UIAxes2.YLim = [0 100];
            app.UIAxes2.XTick = [1 100 200 300];
            app.ExplanatoryVar1 = uibutton(app.UIFigure, 'push');
            app.ExplanatoryVar1.ButtonPushedFcn = createCallbackFcn(app, @ExplanatoryVar1ButtonPushed, true);
            app.ExplanatoryVar1.Text = 'radiation_sum_DE_daily.mat';
            app.ExplanatoryVar2 = uibutton(app.UIFigure, 'push');
            app.ExplanatoryVar2.ButtonPushedFcn = createCallbackFcn(app, @ExplanatoryVar2ButtonPushed, true);
            app.ExplanatoryVar2.Text = 'windspeed_DE_daily.mat';
            app.ExplanatoryvariablesolarLabel_2 = uilabel(app.UIFigure);
            app.ExplanatoryvariablesolarLabel_2.Text = 'Explanatory variable | solar';
            app.ExplanatoryvariablewindLabel_2 = uilabel(app.UIFigure);
            app.ExplanatoryvariablewindLabel_2.Text = 'Explanatory variable | wind';
            app.ClosecurrentfiguresButton = uibutton(app.UIFigure, 'push');
            app.ClosecurrentfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ClosecurrentfiguresButtonPushed, true);
            app.ClosecurrentfiguresButton.Text = 'Close current figures';
            app.AlgorithmtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmtypeButtonGroupSelectionChanged, true);
            app.AlgorithmtypeButtonGroup.Title = 'Algorithm type';
            app.QuantilesButton = uiradiobutton(app.AlgorithmtypeButtonGroup);
            app.QuantilesButton.Text = 'Quantiles';
            app.QuantilesButton.Value = true;
            app.LimitsButton = uiradiobutton(app.AlgorithmtypeButtonGroup);
            app.LimitsButton.Text = 'Limits';
            app.LowerlimitLabel = uilabel(app.UIFigure);
            app.LowerlimitLabel.Text = 'Lower limit [%]';
            app.LowerlimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerlimitEditField.Enable = 'off';
            app.LowerlimitEditField.Value = 10;
            app.HigherlimitLabel = uilabel(app.UIFigure);
            app.HigherlimitLabel.Text = 'Higher limit [%]';
            app.HigherlimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.HigherlimitEditField.Enable = 'off';
            app.HigherlimitEditField.Value = 40;
            app.ClearworkspaceandcommandwindowButton = uibutton(app.UIFigure, 'push');
            app.ClearworkspaceandcommandwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ClearworkspaceandcommandwindowButtonPushed, true);
            app.ClearworkspaceandcommandwindowButton.Text = 'Clear workspace and command window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = weather_impact_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end